classdef BatikApps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CitraRGBPanel              matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        KlasifikasiBatikMarkLabel  matlab.ui.control.Label
        CitraGrayscalePanel        matlab.ui.container.Panel
        UIAxes2                    matlab.ui.control.UIAxes
        HasilKlasifikasiPanel      matlab.ui.container.Panel
        EditField_2                matlab.ui.control.EditField
        PengolahanPanel            matlab.ui.container.Panel
        PilihCitraButton           matlab.ui.control.Button
        Button2                    matlab.ui.control.Button
        Button3                    matlab.ui.control.Button
        Button4                    matlab.ui.control.Button
        Button5                    matlab.ui.control.Button
        Button6                    matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        HasilEkstraksiCiriPanel    matlab.ui.container.Panel
        MULabel                    matlab.ui.control.Label
        DeviasiLabel               matlab.ui.control.Label
        SkewnessLabel              matlab.ui.control.Label
        EnergiLabel                matlab.ui.control.Label
        EntropiLabel               matlab.ui.control.Label
        SmoothnessLabel            matlab.ui.control.Label
        EditField2                 matlab.ui.control.EditField
        EditField3                 matlab.ui.control.EditField
        EditField4                 matlab.ui.control.EditField
        EditField5                 matlab.ui.control.EditField
        EditField6                 matlab.ui.control.EditField
        EditField7                 matlab.ui.control.EditField
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihCitraButton
        function PilihCitraButtonPushed(app, event)
            % menampilkan menu "pilih citra"
            [nama_file,nama_folder]=uigetfile('.jpg');
            drawnow
            figure(app.UIFigure)
            
            % jika ada nama file yang dipilih maka akan mengeksekusi
            % perintah dibawah ini
            if ~isequal(nama_file,0)
                % membaca file citra rgb
                Img = imread(fullfile(nama_folder, nama_file));
                % menampilkan citra RGB pada Axes
                imshow(Img,'Parent',app.UIAxes)
                % menampilkan nama file pada edit field
                app.EditField.Value = nama_file;
                % menyimpan variabel img pada property
                app.Property.Img = Img;
            end
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            % memanggil variabel img yang ada diproperty
            Img = app.Property.Img;
            % mengkonversi citra rgb menjadi citra grayscale
            Img_gray = rgb2gray(Img);
            % menampilkan citra grayscale pada axes
            imshow(Img_gray,'Parent',app.UIAxes2)
            % menyimpan variabel img pada property
            app.Property.Img_gray = Img_gray;
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            % memanggil variabel img yang ada diproperty
            Img_gray = app.Property.Img_gray;
            % melakukan ekstraksi ciri tekstur orde satu
            % membaca ukuran citra 
            [m,n] = size(Img_gray);
            % menghitung frekuensi aras keabuan
            L = 256;
            Frek = zeros(L,1);
            F = double(Img_gray);
            for i = 1:m
                for j = 1:n
                    intensitas = F(i,j);
                    Frek(intensitas+1) = Frek(intensitas+1)+1;
                end
            end
            % menghitung probabilitas
            jum_piksel = m*n;
            for i = 0:L-1
                Prob(i+1) = Frek(i+1)/jum_piksel;
            end
            % menghitung nilai mu
            mu = 0;
            for i = 0:L-1
                mu = mu+i*Prob(i+1);
            end
            % menghitung nilai standart deviasi
            varians = 0;
            for i = 0:L-1
                varians = varians+(i-mu)^2*Prob(i+1);
            end
            deviasi = sqrt(varians);
            varians_n = varians/(L-1)^2;    % Normalisasi
            % menghitung nilai skewness
            skewness = 0;
            for i = 0:L-1
                skewness = skewness+(i-mu)^3*Prob(i+1);
            end
            skewness = skewness/(L-1)^2;    % Normalisasi
            % menghitung nilai energi
            energi = 0;
            for i = 0:L-1
                energi = energi+Prob(i+1)^2;
            end
            % menghitung nilai entropi
            entropi = 0;
            for i = 0:L-1;
                if Prob(i+1) ~= 0
                    entropi = entropi+Prob(i+1)*log(Prob(i+1));
                end
            end
            entropi = -entropi;
            % menghitung smoothness
            smoothness = 1-1/(1+varians_n);
            % menyusun variable data_uji
            data_uji(1,1) = mu;
            data_uji(2,1) = deviasi;
            data_uji(3,1) = skewness;
            data_uji(4,1) = energi;
            data_uji(5,1) = entropi;
            data_uji(6,1) = smoothness;
            smoothness = 1-1/(1+varians_n);
            app.EditField2.Value = num2str(mu);
            app.EditField3.Value = num2str(deviasi);
            app.EditField4.Value = num2str(skewness);
            app.EditField5.Value = num2str(energi);
            app.EditField6.Value = num2str(entropi);
            app.EditField7.Value = num2str(smoothness);
            % menyimpan variabel data_uji pada property
            app.Property.data_uji = data_uji;
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            % memanggil variabel data_uji yang ada diproperty
            data_uji = app.Property.data_uji;
            % memanggil arsitektur jaringan hasil pelatihan
            load jaringan.mat 
            % membaca nilai keluaran hasil pengujian
            Y = sim(jaringan,data_uji);
            hasil_uji = vec2ind(Y);
            % mengubah nilai keluaran menjadi kelas keluaran 
            switch hasil_uji
                case 1
                    kelas_keluaran = 'Batik Mark';
                case 2
                    kelas_keluaran = 'Batik Biasa';
                otherwise
                    kelas_keluaran = 'Tidak Dikenali';
            end
            % menampilkan kelas keluaran pada edit field
            app.EditField_2.Value = kelas_keluaran;
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            % mereset button
            app.EditField.Value = '';
            app.EditField_2.Value = '';
            
            cla(app.UIAxes,'reset')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            
            cla(app.UIAxes2,'reset')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.EditField2.Value = '';
            app.EditField3.Value = '';
            app.EditField4.Value = '';
            app.EditField5.Value = '';
            app.EditField6.Value = '';
            app.EditField7.Value = '';
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            app = WelcomeApps;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CitraRGBPanel = uipanel(app.UIFigure);
            app.CitraRGBPanel.Title = 'Citra RGB';
            app.UIAxes = uiaxes(app.CitraRGBPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'logo.png';
            app.KlasifikasiBatikMarkLabel = uilabel(app.Panel);
            app.KlasifikasiBatikMarkLabel.Text = 'Klasifikasi Batik Mark';
            app.CitraGrayscalePanel = uipanel(app.UIFigure);
            app.CitraGrayscalePanel.Title = 'Citra Grayscale';
            app.UIAxes2 = uiaxes(app.CitraGrayscalePanel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.HasilKlasifikasiPanel = uipanel(app.UIFigure);
            app.HasilKlasifikasiPanel.Title = 'Hasil Klasifikasi';
            app.EditField_2 = uieditfield(app.HasilKlasifikasiPanel, 'text');
            app.PengolahanPanel = uipanel(app.UIFigure);
            app.PengolahanPanel.Title = 'Pengolahan';
            app.PilihCitraButton = uibutton(app.PengolahanPanel, 'push');
            app.PilihCitraButton.ButtonPushedFcn = createCallbackFcn(app, @PilihCitraButtonPushed, true);
            app.PilihCitraButton.Text = 'Pilih Citra';
            app.Button2 = uibutton(app.PengolahanPanel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Grayscale';
            app.Button3 = uibutton(app.PengolahanPanel, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = 'Ekstraksi Ciri';
            app.Button4 = uibutton(app.PengolahanPanel, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = 'Klasifikasi';
            app.Button5 = uibutton(app.PengolahanPanel, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = 'Reset';
            app.Button6 = uibutton(app.PengolahanPanel, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = 'Kembali';
            app.EditField = uieditfield(app.PengolahanPanel, 'text');
            app.HasilEkstraksiCiriPanel = uipanel(app.UIFigure);
            app.HasilEkstraksiCiriPanel.Title = 'Hasil Ekstraksi Ciri';
            app.MULabel = uilabel(app.HasilEkstraksiCiriPanel);
            app.MULabel.Text = 'MU';
            app.DeviasiLabel = uilabel(app.HasilEkstraksiCiriPanel);
            app.DeviasiLabel.Text = 'Deviasi';
            app.SkewnessLabel = uilabel(app.HasilEkstraksiCiriPanel);
            app.SkewnessLabel.Text = 'Skewness';
            app.EnergiLabel = uilabel(app.HasilEkstraksiCiriPanel);
            app.EnergiLabel.Text = 'Energi';
            app.EntropiLabel = uilabel(app.HasilEkstraksiCiriPanel);
            app.EntropiLabel.Text = 'Entropi';
            app.SmoothnessLabel = uilabel(app.HasilEkstraksiCiriPanel);
            app.SmoothnessLabel.Text = 'Smoothness';
            app.EditField2 = uieditfield(app.HasilEkstraksiCiriPanel, 'text');
            app.EditField3 = uieditfield(app.HasilEkstraksiCiriPanel, 'text');
            app.EditField4 = uieditfield(app.HasilEkstraksiCiriPanel, 'text');
            app.EditField5 = uieditfield(app.HasilEkstraksiCiriPanel, 'text');
            app.EditField6 = uieditfield(app.HasilEkstraksiCiriPanel, 'text');
            app.EditField7 = uieditfield(app.HasilEkstraksiCiriPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatikApps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end